classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        roadSelection_ListBox         matlab.ui.control.ListBox
        Label2                        matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        selectTime_Slider             matlab.ui.control.Slider
        generateVisualization_Button  matlab.ui.control.Button
        date_DropDown                 matlab.ui.control.DropDown
        tickTime_DropDown             matlab.ui.control.DropDown
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.date_DropDown.Enable = false;
            app.tickTime_DropDown.Enable = false;
            app.selectTime_Slider.Enable = false;
            app.generateVisualization_Button.Enable = 'off';
            %{
            % Define the URL
            link = 'https://data.transportation.gov/resource/8ect-6jqj.json?location=';
            
            url = [link,app.roadSelection_ListBox.Value];
            
            % Specify the app token if needed
            appToken = 'VNEI5hNy8NgCnI0lmzVtmlCE1';
            options = weboptions('KeyName', 'app_token', 'KeyValue', appToken);
            
            % Retrieve data from the URL
            data = webread(url, options);
            
            % Extract unique locations
            locations = unique({data.location});
            
            % Create a MATLAB app and populate the dropdown
            app.roadSelection_ListBox.Items = locations;
            %}
        end

        % Callback function
        function roadSelection_ListBoxValueChanged(app, event)
            value = app.roadSelection_ListBox.Value;
            app.date_DropDown.Enable = true;
            app.tickTime_DropDown.Enable = true;
            app.generateVisualization_Button.Enable = 'on';
            
            switch value
                case "us-101"
                    Items = ["7:50 - 8:05", "8:05 - 8:20", "8:20 - 8:35"];
                    ItemsData = [
                        [1118814600, 1118815500],
                        [1118815500, 1118816400],
                        [1118816400, 1118817300]
                    ];
                    app.date_DropDown.Items = Items;
                    app.date_DropDown.ItemsData = ItemsData;
            
                case "i-80"
                    Items = ["16:00 - 16:15", "17:00 - 17:15", "17:15 - 17:30"];
                    ItemsData = [
                        [1113400800, 1113401700],
                        [1113404400, 1113405300],
                        [1113405300, 1113406200]
                    ];
                    app.date_DropDown.Items = Items;
                    app.date_DropDown.ItemsData = ItemsData;
            
                case "lankershim"
                    Items = ["8:30 - 8:45", "8:45 - 9:00"];
                    ItemsData = [
                        [1118903400, 1118904300],
                        [1118904300, 1118905200]
                    ];
                    app.date_DropDown.Items = Items;
                    app.date_DropDown.ItemsData = ItemsData;
            
                case "peachtree"
                    Items = ["12:45 - 13:00", "16:00 - 16:15"];
                    ItemsData = [
                        [1162986300, 1162987200],
                        [1162998000, 1162998900]
                    ];
                    app.date_DropDown.Items = Items;
                    app.date_DropDown.ItemsData = ItemsData;
            
                otherwise
                    app.date_DropDown.Enable = false;
                    app.tickTime_DropDown.Enable = false;
            end

        end

        % Value changed function: roadSelection_ListBox
        function roadSelection_ListBoxValueChanged2(app, event)
            value = app.roadSelection_ListBox.Value;
                        app.date_DropDown.Enable = true;
            app.tickTime_DropDown.Enable = true;
          
            
            switch value
            case "us-101"
                Items = ["7:50 - 8:05", "8:05 - 8:20", "8:20 - 8:35"];
                ItemsData = {
                    [1118814600, 1118815500],
                    [1118815500, 1118816400],
                    [1118816400, 1118817300]
                };
                app.date_DropDown.Items = Items;
                app.date_DropDown.ItemsData = ItemsData;
        
            case "i-80"
                Items = ["16:00 - 16:15", "17:00 - 17:15", "17:15 - 17:30"];
                ItemsData = {
                    [1113400800, 1113401700],
                    [1113404400, 1113405300],
                    [1113405300, 1113406200]
                };
                app.date_DropDown.Items = Items;
                app.date_DropDown.ItemsData = ItemsData;
        
            case "lankershim"
                Items = ["8:30 - 8:45", "8:45 - 9:00"];
                ItemsData = {
                    [1118903400, 1118904300],
                    [1118904300, 1118905200]
                };
                app.date_DropDown.Items = Items;
                app.date_DropDown.ItemsData = ItemsData;
        
            case "peachtree"
                Items = ["12:45 - 13:00", "16:00 - 16:15"];
                ItemsData = {
                    [1162986300, 1162987200],
                    [1162998000, 1162998900]
                };
                app.date_DropDown.Items = Items;
                app.date_DropDown.ItemsData = ItemsData;
        
            otherwise
                app.date_DropDown.Enable = false;
                app.tickTime_DropDown.Enable = false;
        end

            
        end

        % Button pushed function: generateVisualization_Button
        function generateVisualization_ButtonPushed(app, event)
            app.selectTime_Slider.Enable = true;
            
            app.selectTime_Slider.Limits = [900/(app.tickTime_DropDown.Value), 900];
            app.selectTime_Slider.SliderStep = [900/(app.tickTime_DropDown.Value), 1];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.tickTime_DropDown = uidropdown(app.UIFigure);
            app.tickTime_DropDown.Items = {'15 minutes', '60 seconds', '30 seconds', '10 seconds'};
            app.tickTime_DropDown.ItemsData = {'1', '15', '30', '90'};
            app.tickTime_DropDown.Value = '15';
            app.date_DropDown = uidropdown(app.UIFigure);
            app.date_DropDown.Items = {};
            app.date_DropDown.Placeholder = 'choose time';
            app.date_DropDown.Value = {};
            app.generateVisualization_Button = uibutton(app.UIFigure, 'push');
            app.generateVisualization_Button.ButtonPushedFcn = createCallbackFcn(app, @generateVisualization_ButtonPushed, true);
            app.generateVisualization_Button.Text = 'Generate Visualization';
            app.selectTime_Slider = uislider(app.UIFigure);
            app.selectTime_Slider.MajorTicks = [];
            app.selectTime_Slider.MajorTickLabels = {''};
            app.selectTime_Slider.MinorTicks = [];
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.roadSelection_ListBox = uilistbox(app.UIFigure);
            app.roadSelection_ListBox.Items = {'i-80', 'us-101', 'peachtree', 'lankershim'};
            app.roadSelection_ListBox.ValueChangedFcn = createCallbackFcn(app, @roadSelection_ListBoxValueChanged2, true);
            app.roadSelection_ListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end